classdef HundirFlota < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Hundir_la_flota     matlab.ui.Figure
        MainMenu            matlab.ui.container.Panel
        BackGround          matlab.ui.control.Button
        HundirlaflotaLabel  matlab.ui.control.Label
        StartGameButton     matlab.ui.control.Button
        Scanning            matlab.ui.container.Panel
        BackGround_2        matlab.ui.control.Button
        Escaneo             matlab.ui.control.Label
        StartgameButton     matlab.ui.control.StateButton
        ScanButton          matlab.ui.control.StateButton
        PreviewPanel        matlab.ui.container.Panel
        Game                matlab.ui.container.Panel
        Turn                matlab.ui.control.StateButton
        GameBoard           matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function board = tablero(app)
            cam=webcam;
            ax = uiaxes('Parent',app.PreviewPanel, 'Position', [-100 -100 680 390]);
            im = image(ax, zeros(720,1280));
            preview(cam, im);
            pause(5);
            tab=snapshot(cam);
            closePreview(cam);
            clear cam;
            imshow(tab, 'Parent', ax);
            board = tablero_digital(app, tab, ax);
        end
        
        function board = tablero_digital(app, tab, ax)
            %imagen=rgb2gray(tab);
            imag = imread('Tablero 10x10.jpg');
            imagen = rgb2gray(imag);
            [lines,E]=CalcLines(imagen);
            [x,y,col,fil,xs,ys]=CalcBigLines(lines);
            [Xcol,Ycol,Xfil,Yfil]=Ord(x,y,col,fil);
            % Take Squares functionapp.StartgameButton
            imshow(imagen, 'Parent', ax);
            for i=1:10
                for j=1:10
                    [xC,yC,xLine,yLine]=makeSquare(Xcol(j:j+1,:),Ycol(j:j+1,:),Xfil(i:i+1,:),Yfil(i:i+1,:));
                    im{i,j}=imcrop(imagen,[xC,yC,xLine,yLine]);
                end
            end
            
            ocupadas=search(im);
            %[por,buque,submarino,lancha]=barcos(ocupadas);
            board = createMatrix(ocupadas);
        end
        
        function [lines, E] = CalcLines(imagen)
            % Calcula las lineas de la imagen introducida.
            % En nuestro caso realiza trans. binarias hasta encontrar las 22 lineas 
            % que sabemos que tiene.
            % Salida: lines-> struct lines.
            % E-> Imagen sobre la que esas lineas fueron calculadas.
            max_dist=sqrt(size(imagen,1)^2+size(imagen,2)^2);
            validation=0;
            i=0;
        
            while i<250 && validation~=22
                i=i+10;
                imagen2=imagen>i;
                E=edge(imagen2,'canny');
                [H, theta, rho]=hough(E);
        
                peaks=houghpeaks(H,22);
                validation=length(peaks);
                lines=houghlines(E, theta, rho, peaks, 'FillGap', max_dist);
            end
        end
        
        function [x,y,columna,fila,xs,ys] = CalcBigLines(lines)
            %Averigua si las lineas perteneces a columnas o filas, luego alarga las
            %lineas para obtener un mejor resultado.
            %·Entrada: lines-> struct lines empleada.
            %·Salida:  x-> Nuevos valores de la x.
            %          y-> Nuevos valores de la y.
            %          columna-> Indica que lineas son columnas.
            %          linea-> Indica que lineas son filas.
            %          xs-> Viejos valores de la x.
            %          ys-> Viejos valores de la y.
            x=[lines(1).point1(1),lines(1).point2(1)];
            xs=[lines(1).point1(1),lines(1).point2(1)];
            y=[lines(1).point1(2),lines(1).point2(2)];
            ys=[lines(1).point1(2),lines(1).point2(2)];
            for k=2:length(lines)
                x=[x;lines(k).point1(1),lines(k).point2(1)];
                xs=[xs;lines(1).point1(1),lines(1).point2(1)];
                y=[y;lines(k).point1(2),lines(k).point2(2)];
                ys=[ys;lines(1).point1(2),lines(1).point2(2)];
            end
        
            i=0;
            j=0;
            for k=1:length(lines)
                l=abs(x(k,1)-x(k,2));
                c=abs(y(k,1)-y(k,2));
                if l>c
                    i=i+1;
                    fila(i)=k;
                end
                if c>l
                    j=j+1;
                    columna(j)=k;
                end
            end
        
            x(fila,1)=x(fila,1)-200;
            x(fila,2)=x(fila,2)+200;
        
            y(columna,1)=y(columna,1)-200;
            y(columna,2)=y(columna,2)+200;
        end
        
        function [Xcol,Ycol,Xfil,Yfil] = Ord(x,y,col,fil)
            % Devuelve columnas y filas separadas.
            % Las ordena dependiendo de si son filas o columnas.
            % Salida: columnas-> x y de las columnas.
            % fila-> x y de las filas.
            columnas=[x(col,1),x(col,2),y(col,1),y(col,2)];
            filas=[x(fil,1),x(fil,2),y(fil,1),y(fil,2)];
            % Ordenamos en funcion de las x.
            [~,idx]=sort(columnas(:,1));      
            columnas=columnas(idx,:);
            % Ordenamos en funcion de las y.
            [~,idy]=sort(filas(:,3));         
            filas=filas(idy,:);
            Xcol=[columnas(:,1),columnas(:,2)];
            Xfil=[columnas(:,3),columnas(:,4)];
            Ycol=[filas(:,1),filas(:,2)];
            Yfil=[filas(:,3),filas(:,4)];
        end
        
        
        function ocupadas = search(im)
            ok=1;
            for i=1:10
               for j=1:10
                  c1=im{i,j}>10;
                  c2=im{i,j}<=125;
                  oscuros=length(im{i,j}(c1&c2));
                  c1=im{i,j}>130;
                  c2=im{i,j}<=255;
                  claros=length(im{i,j}(c1&c2));
                  if oscuros>claros
                      ocupadas(ok,:)=[i,j];
                      ok=ok+1;
                  end
                end
            end
        end
        
        function [por,buque,submarino,lancha] = barcos(app, ocupadas)
            for loop=1:4
            pos(1,:)=ocupadas(1,:);
            barco(1,:)=ocupadas(1,:);
            toErase(1)=1;
            p=2;
            for i=2:length(ocupadas(:,1))
                if ocupadas(i,1)==pos(1,1) %Si fila==
                    if ocupadas(i,2)==pos(1,2)+1 %Igual a columna+1
                        pos(1,:)=ocupadas(i,:);
                        barco(p,:)=ocupadas(i,:);
                        toErase(i)=i;
                        p=p+1;
                    end
                end
                if ocupadas(i,2)==pos(1,2) %Si col==
                    if ocupadas(i,1)==pos(1,1)+1 %Igual a fila+1
                        pos(1,:)=ocupadas(i,:); 
                        barco(p,:)=ocupadas(i,:);
                        toErase(i)=i;
                        p=p+1;
                    end
                end
            end
            ocupadas([toErase],:)=[];
            long=length(barco(:,1));
            switch long
                case 1
                    lancha=barco;
                case 3
                    submarino=barco;
                case 4
                    buque=barco;
                case 5
                    por=barco;
            end
            clear toErase;
            clear pos;
            clear barco;
        end
        end
        
        function number = ReconocerNumero(app)
            load prototipos
            ceps = ComandoVoz(app);
            comparations = zeros(4, 10);
        
            for j = 1:10
                for i = 1:4
                    comparations(i,j) = DTW(app,prototipos{i,j}(:), ceps(:));
                end
            end
            
            a = min(comparations);
            [~, number] = min(a);
            
            if(number == 10)
                number = 0;
            end
        end
        
        function cepstrum = ComandoVoz(app)
            x = 0;  
            t = 3;
            Fs = 8000;
            num_bits = 16;
            Ch = 1;
            recObj = audiorecorder(Fs, num_bits, Ch);
            disp('Start speaking.')
            recordblocking(recObj, t);
            disp('End of Recording.');
            play(recObj);
            %2.
            senal =  getaudiodata(recObj);
            %Corrección del micro: no detecta nada el primer medio segundo.
            %(Deshabilitar para ordenador genérico)
            %senal = senal(Fs/2 + 1:end);
            
            %figure,plot(senal);
            a = 0.95;
            senal_filtrada = filter([1 -a], 1, senal);
            %figure; plot(senal_filtrada);
        
            %3.
            tam_seg = Fs*0.03;
            tam_desp = tam_seg/4;
            tam_solap = tam_seg - tam_desp;
            segmentos = buffer(senal, tam_seg, tam_solap, 'nodelay');
            ventana = hanning(tam_seg);
            ventanas = repmat(ventana, 1, size(segmentos, 2));
            segmentos_enventanados = segmentos .* ventanas;
            %4.
            %Energía localizada (por segmento):
            E = sum(segmentos.^2 .* ventanas.^2);
            %Magnitud localizada:
            M = sum(abs(segmentos).*ventanas);
            %Tasa cruces por cero:
            A = segmentos(1:end-1,:);
            B = segmentos(2:end,:);
            ventanaC = repmat(hanning(tam_seg - 1), 1, size(segmentos,2));
            tasaCruces = sum(abs(sign(A) - sign(B)).*ventanaC)/(2*size(segmentos,1));
        
            %5.
            M2 = sum(abs(segmentos));
            M2s = M2(1:10);
            tasaCrucesS = tasaCruces(1:10);
            mediaM = mean(M2s);
            mediaZ = mean(tasaCrucesS);
            stdM = std(M2s);
            stdZ = std(tasaCrucesS);
        
            UmbSupNRG = max(M2)/2;
            UmbInfNRG = mediaM + 2*stdM;
            UmbCruCero = mediaZ + 2*stdZ;
        
            n = 11;
            while(M2(n) < UmbSupNRG)
                n = n+1;
            end
        
            while(M2(n) > UmbInfNRG && n > 11)
                n = n - 1;
            end
        
            le = n;
            contador = 0;
            inicio = le;
            while(n > le - 25 && n > 11)
                n = n - 1;
                if(tasaCruces(n) > UmbCruCero)
                    contador = contador + 1;
                    if(contador >= 3)
                        inicio = n;
                    end
                else
                    contador = 0;
                end   
            end
        
            %Calcular fin de palabra
            M2s = M2(end-10:end);
            tasaCrucesS = tasaCruces(end-10:end);mediaM = mean(M2s);
            mediaZ = mean(tasaCrucesS);
            stdM = std(M2s);
            stdZ = std(tasaCrucesS);
            UmbInfNRG = mediaM + 2*stdM;
            UmbCruCero = mediaZ + 2*stdZ;
        
            n = length(M2)-10;
            while(M2(n) < UmbSupNRG)
                n = n-1;
            end
        
            while(M2(n) > UmbInfNRG && n < length(M2)-10)
                n = n + 1;
            end
        
            le = n;
            contador = 0;
            fin = le;
            while(n < le + 25 && n < length(M2)-10)
                n = n + 1;
                if(tasaCruces(n) > UmbCruCero)
                    contador = contador + 1;
                    if(contador >= 3)
                        fin = n;
                    end
                else
                    contador = 0;
                end   
            end


            %6
        %     senal_original = 0;
        %     for i = inicio:fin
        %         b = segmentos(1:end-tam_solap,i);
        %         senal_original = [senal_original;b];
        %     end
        %     figure;
            %plot(senal_original);
        % 
        %     obj_senal = audioplayer(senal_original, Fs);
        %     play(obj_senal);
        
            senal_sonora = senal(tam_seg + inicio*tam_desp:tam_seg+fin*tam_desp);
        
            %filas a coge de cepstrum
            nfilas = 15;
            %cantidad de p a utilizar
            pvalues = 5;
        
            cepstrum = real(ifft(log(abs(fft(segmentos_enventanados(:, inicio:fin))))));
            cepstrum = cepstrum(2:nfilas+1,:);
        
            %añadir a cepstrum p columnas de ceros a ambos lados
            cepstrumaux = [zeros(nfilas,pvalues) cepstrum zeros(nfilas,pvalues)];
        
            %crear matriz desde -p a p, con nfilas filas
            p = -pvalues:pvalues;
            pmat = repmat(p,nfilas,1);
        
            filaSegs = [zeros(1,pvalues) ones(1, size(cepstrum,2)), zeros(1,pvalues)];
        
            cepstrum = [cepstrum; zeros(size(cepstrum))]; %reserva memoria para cepstrum delta
        
            %bucle, calcula los deltas columna a columna.
            for i = 1:size(cepstrumaux, 2)-pvalues*2
                a = cepstrumaux(:, i:i+pvalues*2).*pmat;
        
                denom = sum((p .* filaSegs(i:i+pvalues*2)).^2);
        
                cepstrum(nfilas+1:end,i) = sum(a,2)/denom;
            end
        
            %3.
            means = mean(cepstrum);
            stds = std(cepstrum);
            means = repmat(means, size(cepstrum, 1), 1);
            stds = repmat(stds, size(cepstrum, 1), 1);
        
            %Normalización.
            cepstrum = cepstrum-means./stds;
        end
        
        function sim = DTW(app,x1,x2)
            matrix = zeros(length(x1), length(x2));
    
            matrix(1) = abs(x1(1) - x2(1));
            
            for i = 2:length(x1)
                matrix(i,1) = matrix(i-1,1)+abs(x1(i) - x2(1));
            end
            
            for j = 2:length(x2)
                matrix(1, j) = matrix(1,j-1) + abs(x1(1) - x2(j));
                for i = 2:length(x1)
                    matrix(i,j) = min([matrix(i-1,j) matrix(i, j-1) matrix(i-1,j-1)]) + abs(x1(i)-x2(j));
                end
            end
            sim = matrix(end);
        end
        
        function matrix = createBoard(app, ocupadas)
            matrix = zeros(10,10);
            for i=1:10
                for j=1:10
                    if length(find(ocupadas(:,1)==i & ocupadas(:,2)==j))==1
                      matrix(i,j)=1;
                    else
                      matrix(i,j)=0;
                    end
                end
            end
        end
        
        
        function linea = writeTab(app)
            global board; 
            n = 10;
             tab = cell(n+1,n+1);
             % initialize the board
             for u=1:n+1
                 for v=1:n+1
                     if u==1
                         if v<=n
                             tab{u,v+1} = int2str(v);
                             tab{v+1,u} = int2str(v);
                         end
                     elseif v==1
                         % do nothing
                     else
                         tab{u,v} = ' ';
                     end
                 end
             end
             
             for i = 2:n+1
                 for j = 2:n+1
                     if board(i-1,j-1) == 2
                         tab{i,j} = 'X';
                     else
                         if board(i-1, j-1) == 3
                             tab{i,j} = 'O';
                         end
                     end
                 end
             end
             
             linea = [];
             for i = 1:n+1
                 line = [];
                 for j = 1:n+1
                     line = [line ' ' tab{i,j}];
                 end
                 if i == 11
                    linea = [linea;line(2:end)];
                 else
                    linea = [linea;line];
                 end
             end
        end
        
    end

    methods (Access = private)

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            app.Scanning.Visible = 'on';
        end

        % Value changed function: ScanButton
        function ScanButtonPushed(app, event)
            global board; 
            board = tablero(app);
            global count; 
            count = 0;
            global succesNumber;
            succesNumber = length(find(board==1));
            global blankBoard; 
            blankBoard = zeros(10,10);
            imagesc(app.GameBoard, blankBoard);
        end

        % Value changed function: StartgameButton
        function StartgameButtonValueChanged(app, event)
            app.Game.Visible = 'on';
        end

        % Value changed function: Turn
        function Turns(app, event)
            global board;
            global count;
            global succesNumber;
            global blankBoard;
            
            questdlg('Hable por favor');
            number1 = ReconocerNumero(app);
            questdlg('Hable por favor');
            number2 = ReconocerNumero(app);
            if board(number1, number2) == 1
                count = count + 1;
                blankBoard(number1,number2) = 1;
            else 
                if board(number1, number2) == 0
                    blankBoard(number1,number2) = 2;
                end
            end
           
            imagesc(app.GameBoard, blankBoard);
            
            if count == succesNumber
               questdlg('Enhorabuena ha terminado el juego');
               %close app.Hundir_la_flota;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Hundir_la_flota = uifigure;
            app.Hundir_la_flota.Name = 'Hundir la flota';
            app.MainMenu = uipanel(app.Hundir_la_flota);
            app.BackGround = uibutton(app.MainMenu, 'push');
            app.BackGround.Icon = 'Sh.jpg';
            app.BackGround.Text = '';
            app.HundirlaflotaLabel = uilabel(app.MainMenu);
            app.HundirlaflotaLabel.Text = 'Hundir la flota';
            app.StartGameButton = uibutton(app.MainMenu, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.Scanning = uipanel(app.MainMenu);
            app.Scanning.Visible = 'off';
            app.BackGround_2 = uibutton(app.Scanning, 'push');
            app.BackGround_2.Icon = 'Sh.jpg';
            app.BackGround_2.Text = '';
            app.Escaneo = uilabel(app.Scanning);
            app.Escaneo.Text = 'Escanee su tablero';
            app.StartgameButton = uibutton(app.Scanning, 'state');
            app.StartgameButton.ValueChangedFcn = createCallbackFcn(app, @StartgameButtonValueChanged, true);
            app.StartgameButton.Text = 'Start game';
            app.ScanButton = uibutton(app.Scanning, 'state');
            app.ScanButton.ValueChangedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.PreviewPanel = uipanel(app.Scanning);
            app.Game = uipanel(app.Scanning);
            app.Game.Visible = 'off';
            app.Turn = uibutton(app.Game, 'state');
            app.Turn.ValueChangedFcn = createCallbackFcn(app, @Turns, true);
            app.Turn.Text = 'Play turn!';
            app.GameBoard = uiaxes(app.Game);
        end
    end

    methods (Access = public)

        % Construct app
        function app = HundirFlota

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Hundir_la_flota)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Hundir_la_flota)
        end
    end
end